classdef Illustrate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IllustrateDIPProjectUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        QuitMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        InputsPanel                   matlab.ui.container.Panel
        resultLabel_4                 matlab.ui.control.Label
        ChooseFileButton              matlab.ui.control.Button
        resultLabel_3                 matlab.ui.control.Label
        resultLabel_2                 matlab.ui.control.Label
        NoofColorsEditField           matlab.ui.control.NumericEditField
        NoofColorsEditFieldLabel      matlab.ui.control.Label
        UploadImageTextArea           matlab.ui.control.TextArea
        UploadImageTextAreaLabel      matlab.ui.control.Label
        GenerateIllustrationButton    matlab.ui.control.Button
        SegmentationButtonGroup       matlab.ui.container.ButtonGroup
        LazySnappingButton            matlab.ui.control.RadioButton
        KMeansClusteringButton        matlab.ui.control.RadioButton
        ResultPanel                   matlab.ui.container.Panel
        ExportImageButton             matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        AdjustmentsPanel              matlab.ui.container.Panel
        KMeansClustersPanel           matlab.ui.container.Panel
        SelectclusterscontainingdesiredobjectsLabel  matlab.ui.control.Label
        Cluster3CheckBox              matlab.ui.control.CheckBox
        Cluster2CheckBox              matlab.ui.control.CheckBox
        Cluster1CheckBox              matlab.ui.control.CheckBox
        ProceedButton                 matlab.ui.control.StateButton
        ColorQuantizationPanel        matlab.ui.container.Panel
        PlanebyPlaneCheckBox          matlab.ui.control.CheckBox
        DitherCheckBox                matlab.ui.control.CheckBox
        ImageDetailsPanel             matlab.ui.container.Panel
        SpatialSigmaSlider            matlab.ui.control.Slider
        SpatialSigmaSliderLabel       matlab.ui.control.Label
        SmoothnessSlider              matlab.ui.control.Slider
        SmoothnessSliderLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        RGB % input image
        RGB_smooth % smoother image - bilateral filter
        RGB_segmented % segmented object
        RGB_quantized % quantized result
        path % chosen file path
        lz_foreground % foreground mask for lazy snapping segmentation
        lz_background % background mask for lazy snapping segmentation
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)

        function img = load_image(app)
            inImgname = app.UploadImageTextArea.Value; %Input Image name
            inImgLocation = app.path; %File Path of Input Image
            inImgPath = string(inImgLocation) + string(inImgname); %Input Image name
            img = imread(inImgPath); % Read input image
        end
        
        function smoothed = bilfil(app,img,DoS,ss)
            imLAB = rgb2lab(img); % converting image to the L*a*b color space
            smoothedLAB = imbilatfilt(imLAB,DoS,ss); % applying bilateral filtering
            smoothed = lab2rgb(smoothedLAB,"Out","uint8");  % converting image back to RGB color space 
        end
        
        function object = segment(app,img)
            % determining chosen method
            opt_seg = app.SegmentationButtonGroup.SelectedObject; 
            if opt_seg == app.KMeansClusteringButton
                opt_seg = 1;
            elseif opt_seg == app.LazySnappingButton
                opt_seg = 2;
            end
            % segmenting
            if opt_seg == 1 % K-means clustering
                lab_rgb = rgb2lab(img); % converting image from rgb color space to L*a*b* color space
                % classifying the colors in 'a*b*' space using k-means clustering
                ab = lab_rgb(:,:,2:3);
                ab = im2single(ab);
                nColors = 3;
                pixel_labels = imsegkmeans(ab,nColors,'NumAttempts',3); % repeating the clustering 3 times to avoid local minima
                % creating images that segment the original image by color
                mask1 = pixel_labels==1;
                cluster1 = img .* uint8(mask1);
                mask2 = pixel_labels==2;
                cluster2 = img .* uint8(mask2);
                mask3 = pixel_labels==3;
                cluster3 = img .* uint8(mask3);
                %Display clusters side-by-side
                figure; subplot(1,3,1);imshow(cluster1);title('Cluster 1');
                subplot(1,3,2);imshow(cluster2);title('Cluster 2'); xlabel('Select clusters with desired object(s) from the K-Means Clusters Panel.');
                subplot(1,3,3);imshow(cluster3);title('Cluster 3');
                app.KMeansClustersPanel.Visible = 1;
                % wait for user to select clusters
                while(app.ProceedButton.Value ~=1)
                    pause(1);
                    if (app.ProceedButton.Value == 1)
                        if (app.Cluster3CheckBox.Value == 0 && app.Cluster2CheckBox.Value == 0 && app.Cluster1CheckBox.Value == 0)
                            app.ProceedButton.Value = 0;
                            f = msgbox('Must select atleast 1 cluster.');
                        end
                    end
                end
                app.ProceedButton.Value = 0;
                close;
                % Combining clusters for relevant object
                object = getclusters(app,cluster1,cluster2,cluster3);
            elseif opt_seg == 2 % lazy snapping
                % creating a label matrix.
                L = superpixels(img,500);
                % specifying a ROI for foreground
                figure; imshow(img); title('Select 1 foreground region and 2 background regions.');
                f = drawrectangle('Color','g');
                % creating a mask containing foreground pixels
                app.lz_foreground = createMask(f,img);
                % specifying background ROIs.
                b1 = drawrectangle('Color','r');
                b2 = drawrectangle('Color','r');
                % creating a mask containing background pixels -> union of the two background ROIs
                app.lz_background = createMask(b1,img) + createMask(b2,img);
                close;
                % performing lazy snapping
                BW = lazysnapping(img,L,app.lz_foreground,app.lz_background);
                % creating a masked image with a black background
                object = img;
                object(repmat(~BW,[1 1 3])) = 0;
            end

        end

        function segmented = segment_update(app,image)
            % determining chosen method
            opt_seg = app.SegmentationButtonGroup.SelectedObject; 
            if opt_seg == app.KMeansClusteringButton
                opt_seg = 1;
            elseif opt_seg == app.LazySnappingButton
                opt_seg = 2;
            end
            % segmenting
            if opt_seg == 1 % K-means clustering
                % converting image from rgb color space to L*a*b* color space
                lab_rgb = rgb2lab(image);
                % classifying the colors in 'a*b*' space using k-means clustering
                ab = lab_rgb(:,:,2:3);
                ab = im2single(ab);
                nColors = 3;
                pixel_labels = imsegkmeans(ab,nColors,'NumAttempts',3); % repeating the clustering 3 times to avoid local minima
                % creating images that segment the original image by color
                mask1 = pixel_labels==1;
                cluster1 = image .* uint8(mask1);
                mask2 = pixel_labels==2;
                cluster2 = image .* uint8(mask2);
                mask3 = pixel_labels==3;
                cluster3 = image .* uint8(mask3);
                
                app.KMeansClustersPanel.Visible = 1;
                % wait for user to select clusters if needed
                if (app.Cluster3CheckBox.Value == 0 && app.Cluster2CheckBox.Value == 0 && app.Cluster1CheckBox.Value == 0)
                            app.ProceedButton.Value = 0;
                            f = msgbox('Must select atleast 1 cluster.');
                            while(app.ProceedButton.Value ~=1)
                                pause(1);
                                if (app.ProceedButton.Value == 1)
                                    if (app.Cluster3CheckBox.Value == 0 && app.Cluster2CheckBox.Value == 0 && app.Cluster1CheckBox.Value == 0)
                                        app.ProceedButton.Value = 0;
                                        f = msgbox('Must select atleast 1 cluster.');
                                    end
                                end
                            end
                end
                app.ProceedButton.Value = 0;
                % Combining clusters for relevant object
                segmented = getclusters(app,cluster1,cluster2,cluster3);
            elseif opt_seg == 2 % lazy snapping
                % creating a label matrix.
                L = superpixels(image,500);
                % performing lazy snapping
                BW = lazysnapping(image,L,app.lz_foreground,app.lz_background);
                % creating a masked image with a black background
                segmented = image;
                segmented(repmat(~BW,[1 1 3])) = 0;
            end
        end
        
        function combined = getclusters(app,cluster1,cluster2,cluster3)
            if app.Cluster1CheckBox.Value == 1
                combined = cluster1;
                if app.Cluster2CheckBox.Value == 1
                    combined = combined + cluster2;
                end
                if app.Cluster3CheckBox.Value == 1
                    combined = combined + cluster3;
                end
            elseif app.Cluster2CheckBox.Value == 1
                combined = cluster2;
                if app.Cluster3CheckBox.Value == 1
                    combined = combined + cluster3;
                end
            elseif app.Cluster3CheckBox.Value == 1
                    combined = cluster3;
            end
        end
        
        function quantized = quantize(app,smoothed_img)
            if app.PlanebyPlaneCheckBox.Value == 0 % using rgb2ind
                if app.DitherCheckBox.Value == 0
                    [quant,map] = rgb2ind(smoothed_img,app.NoofColorsEditField.Value,'nodither');
                elseif app.DitherCheckBox.Value == 1
                    [quant,map] = rgb2ind(smoothed_img,app.NoofColorsEditField.Value,'dither');
                end
                quantized = ind2rgb(quant,map);
            else %using multithresh
                % Generate thresholds for each plane of the RGB image.
                threshForPlanes = zeros(3,5); 
                for i = 1:3
                    threshForPlanes(i,:) = multithresh(smoothed_img(:,:,i),5);
                end
                % Process each RGB plane separately using the threshold vector computed from the given plane. Quantize each RGB plane using threshold vector generated for that plane.
                quantPlane = zeros( size(smoothed_img) );
                for i = 1:3
                    value = [0 threshForPlanes(i,2:end) 255];
                    quantPlane(:,:,i) = imquantize(smoothed_img(:,:,i),threshForPlanes(i,:),value);
                end
                quantized = uint8(quantPlane);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateIllustrationButton
        function GenerateIllustrationButtonPushed(app, event)
            if ~app.path
                f = msgbox('Please open image to illustrate.');
            else
                app.RGB = load_image(app); % loading input image
                % setting default values for bilateral filtering
                app.SmoothnessSlider.Value = 10; %degree of smoothing - bilateral filter
                app.SpatialSigmaSlider.Value = 1; % spatial sigma - bilateral filter
                app.RGB_smooth = bilfil(app,app.RGB,10,1); % applying bilateral filtering
                app.RGB_segmented = segment(app,app.RGB_smooth); % segmenting the foreground object
                app.RGB_quantized = quantize(app,app.RGB_segmented);
                app.Image.ImageSource = app.RGB_quantized;
            end
        end

        % Value changed function: SmoothnessSlider
        function SmoothnessSliderValueChanged(app, event)
            app.Image.ImageSource = 'rendering_gray.png';
            app.RGB_smooth = bilfil(app,app.RGB,app.SmoothnessSlider.Value,app.SpatialSigmaSlider.Value); % applying bilateral filtering
            app.RGB_segmented = segment_update(app,app.RGB_smooth); % segmenting image
            app.RGB_quantized = quantize(app,app.RGB_segmented); % color quantization
            app.Image.ImageSource = app.RGB_quantized; %displaying result
        end

        % Value changed function: SpatialSigmaSlider
        function SpatialSigmaSliderValueChanged(app, event)
            app.Image.ImageSource = 'rendering_gray.png';
            app.RGB_smooth = bilfil(app,app.RGB,app.SmoothnessSlider.Value,app.SpatialSigmaSlider.Value); % applying bilateral filtering
            app.RGB_segmented = segment_update(app,app.RGB_smooth); % segmenting image
            app.RGB_quantized = quantize(app,app.RGB_segmented); % color quantization
            app.Image.ImageSource = app.RGB_quantized; %displaying result
        end

        % Value changed function: PlanebyPlaneCheckBox
        function PlanebyPlaneCheckBoxValueChanged(app, event)
            % enable dither option if plane-by-plane is not selected (i.e.
            % rgb2ind is selected)
            if app.PlanebyPlaneCheckBox.Value == 0
                app.DitherCheckBox.Enable = 1;
            elseif app.PlanebyPlaneCheckBox.Value == 1
                app.DitherCheckBox.Enable = 0;
                app.DitherCheckBox.Value = 0;
            end
            app.RGB_quantized = quantize(app,app.RGB_segmented); % color quantization
            app.Image.ImageSource = app.RGB_quantized; %displaying result
        end

        % Value changed function: DitherCheckBox
        function DitherCheckBoxValueChanged(app, event)
            % enable plane-by-plane option if dither is not selected (i.e.
            % rgb2ind is not selected)
            if app.DitherCheckBox.Value == 0
                app.PlanebyPlaneCheckBox.Enable = 1;
            elseif app.DitherCheckBox.Value == 1
                app.PlanebyPlaneCheckBox.Enable = 0;
                app.PlanebyPlaneCheckBox.Value = 0;
            end
            app.RGB_quantized = quantize(app,app.RGB_segmented); % color quantization
            app.Image.ImageSource = app.RGB_quantized; %displaying result
        end

        % Selection changed function: SegmentationButtonGroup
        function SegmentationButtonGroupSelectionChanged(app, event)
           % hide kmeans cluster panel if lazy snapping is chosen
            if app.LazySnappingButton.Value == 1 
                app.KMeansClustersPanel.Visible = 0;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,app.path] = uigetfile('*.png');
            if file
                app.UploadImageTextArea.Value = file;
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'Quit Illustrate?','Confirm Close',...
                        'Icon','warning');
            if strcmpi(selection,'Cancel')
                close(fig);
            elseif strcmpi(selection,'OK')
                delete(app);
                close(fig);
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            output = app.RGB_quantized;
            [file,path] = uiputfile;
            location = string(path) + string(file);
            A = double(imbinarize(rgb2gray(output)));      % making bg transparent
            imwrite(output,location, 'Alpha', A);

        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file,app.path] = uigetfile('*.png');
            if file
                app.UploadImageTextArea.Value = file;
            end
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            output = app.RGB_quantized;
            [file,path] = uiputfile;
            location = string(path) + string(file);
            A = double(imbinarize(rgb2gray(output)));      % making bg transparent
            imwrite(output,location, 'Alpha', A);
        end

        % Value changed function: ProceedButton
        function ProceedButtonValueChanged(app, event)
            % wait for user to select clusters if needed
                if (app.Cluster3CheckBox.Value == 0 && app.Cluster2CheckBox.Value == 0 && app.Cluster1CheckBox.Value == 0)
                            app.ProceedButton.Value = 0;
                            f = msgbox('Must select atleast 1 cluster.');
                else
                    app.Image.ImageSource = 'rendering_gray.png';
                    app.RGB_smooth = bilfil(app,app.RGB,app.SmoothnessSlider.Value,app.SpatialSigmaSlider.Value); % applying bilateral filtering
                    app.RGB_segmented = segment_update(app,app.RGB_smooth); % segmenting image
                    app.RGB_quantized = quantize(app,app.RGB_segmented); % color quantization
                    app.Image.ImageSource = app.RGB_quantized; %displaying result
                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IllustrateDIPProjectUIFigure = uifigure('Visible', 'off');
            app.IllustrateDIPProjectUIFigure.Name = 'Illustrate | DIP Project';
            app.FileMenu = uimenu(app.IllustrateDIPProjectUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.HelpMenu = uimenu(app.IllustrateDIPProjectUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.IllustrateDIPProjectUIFigure);
            app.AboutMenu.Text = 'About';
            app.AdjustmentsPanel = uipanel(app.IllustrateDIPProjectUIFigure);
            app.AdjustmentsPanel.Title = 'Adjustments';
            app.ImageDetailsPanel = uipanel(app.AdjustmentsPanel);
            app.ImageDetailsPanel.Title = 'Image Details';
            app.SmoothnessSliderLabel = uilabel(app.ImageDetailsPanel);
            app.SmoothnessSliderLabel.Text = 'Smoothness';
            app.SmoothnessSlider = uislider(app.ImageDetailsPanel);
            app.SmoothnessSlider.Limits = [10 150];
            app.SmoothnessSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSliderValueChanged, true);
            app.SmoothnessSlider.Value = 10;
            app.SpatialSigmaSliderLabel = uilabel(app.ImageDetailsPanel);
            app.SpatialSigmaSliderLabel.Text = 'Spatial Sigma';
            app.SpatialSigmaSlider = uislider(app.ImageDetailsPanel);
            app.SpatialSigmaSlider.Limits = [1 20];
            app.SpatialSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @SpatialSigmaSliderValueChanged, true);
            app.SpatialSigmaSlider.Value = 1;
            app.ColorQuantizationPanel = uipanel(app.AdjustmentsPanel);
            app.ColorQuantizationPanel.Title = 'Color Quantization';
            app.DitherCheckBox = uicheckbox(app.ColorQuantizationPanel);
            app.DitherCheckBox.ValueChangedFcn = createCallbackFcn(app, @DitherCheckBoxValueChanged, true);
            app.DitherCheckBox.Text = 'Dither';
            app.PlanebyPlaneCheckBox = uicheckbox(app.ColorQuantizationPanel);
            app.PlanebyPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlanebyPlaneCheckBoxValueChanged, true);
            app.PlanebyPlaneCheckBox.Text = 'Plane-by-Plane';
            app.KMeansClustersPanel = uipanel(app.AdjustmentsPanel);
            app.KMeansClustersPanel.Title = 'K-Means Clusters';
            app.KMeansClustersPanel.Visible = 'off';
            app.ProceedButton = uibutton(app.KMeansClustersPanel, 'state');
            app.ProceedButton.ValueChangedFcn = createCallbackFcn(app, @ProceedButtonValueChanged, true);
            app.ProceedButton.Text = 'Proceed';
            app.Cluster1CheckBox = uicheckbox(app.KMeansClustersPanel);
            app.Cluster1CheckBox.Text = 'Cluster 1';
            app.Cluster2CheckBox = uicheckbox(app.KMeansClustersPanel);
            app.Cluster2CheckBox.Text = 'Cluster 2';
            app.Cluster3CheckBox = uicheckbox(app.KMeansClustersPanel);
            app.Cluster3CheckBox.Text = 'Cluster 3';
            app.SelectclusterscontainingdesiredobjectsLabel = uilabel(app.KMeansClustersPanel);
            app.SelectclusterscontainingdesiredobjectsLabel.Text = 'Select clusters containing desired object(s):';
            app.ResultPanel = uipanel(app.IllustrateDIPProjectUIFigure);
            app.ResultPanel.Title = 'Result';
            app.Image = uiimage(app.ResultPanel);
            app.Image.Tooltip = {''};
            app.Image.ImageSource = 'res_here.png';
            app.ExportImageButton = uibutton(app.ResultPanel, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.InputsPanel = uipanel(app.IllustrateDIPProjectUIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SegmentationButtonGroup = uibuttongroup(app.InputsPanel);
            app.SegmentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationButtonGroupSelectionChanged, true);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.KMeansClusteringButton = uiradiobutton(app.SegmentationButtonGroup);
            app.KMeansClusteringButton.Tooltip = {'Result will be displayed here'};
            app.KMeansClusteringButton.Text = 'K-Means Clustering';
            app.KMeansClusteringButton.Value = true;
            app.LazySnappingButton = uiradiobutton(app.SegmentationButtonGroup);
            app.LazySnappingButton.Tooltip = {'Result will be displayed here'};
            app.LazySnappingButton.Text = 'Lazy Snapping';
            app.GenerateIllustrationButton = uibutton(app.InputsPanel, 'push');
            app.GenerateIllustrationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateIllustrationButtonPushed, true);
            app.GenerateIllustrationButton.Text = 'Generate Illustration';
            app.UploadImageTextAreaLabel = uilabel(app.InputsPanel);
            app.UploadImageTextAreaLabel.Tooltip = {'Result will be displayed here'};
            app.UploadImageTextAreaLabel.Text = 'Upload Image';
            app.UploadImageTextArea = uitextarea(app.InputsPanel);
            app.UploadImageTextArea.Tooltip = {'Result will be displayed here'};
            app.NoofColorsEditFieldLabel = uilabel(app.InputsPanel);
            app.NoofColorsEditFieldLabel.Tooltip = {'Result will be displayed here'};
            app.NoofColorsEditFieldLabel.Text = 'No. of Colors';
            app.NoofColorsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.NoofColorsEditField.Limits = [0 16];
            app.NoofColorsEditField.RoundFractionalValues = 'on';
            app.NoofColorsEditField.Tooltip = {'Result will be displayed here'};
            app.NoofColorsEditField.Value = 3;
            app.resultLabel_2 = uilabel(app.InputsPanel);
            app.resultLabel_2.Tooltip = {'Result will be displayed here'};
            app.resultLabel_2.Text = 'Choose an image file to generate the illustration from.';
            app.resultLabel_3 = uilabel(app.InputsPanel);
            app.resultLabel_3.Tooltip = {''};
            app.resultLabel_3.Text = 'Enter the number of colors desired in quantized image.';
            app.ChooseFileButton = uibutton(app.InputsPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.resultLabel_4 = uilabel(app.InputsPanel);
            app.resultLabel_4.Tooltip = {'Result will be displayed here'};
            app.resultLabel_4.Text = 'Choose method to extract desired object from the image.';
            % Show the figure after all components are created
            app.IllustrateDIPProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Illustrate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IllustrateDIPProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IllustrateDIPProjectUIFigure)
        end
    end
end